classdef SortingVisualizedProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        VisualizeSortTab              matlab.ui.container.Tab
        MinimumValueSpinnerLabel      matlab.ui.control.Label
        MinimumValueSpinner           matlab.ui.control.Spinner
        MaximumValueSpinnerLabel      matlab.ui.control.Label
        MaximumValueSpinner           matlab.ui.control.Spinner
        ArrayLengthSpinnerLabel       matlab.ui.control.Label
        ArrayLengthSpinner            matlab.ui.control.Spinner
        RandomArrayLabel              matlab.ui.control.Label
        SortedArrayLabel              matlab.ui.control.Label
        LabelUnsorted                 matlab.ui.control.Label
        LabelSorted                   matlab.ui.control.Label
        SortingAlgorithmsButtonGroup  matlab.ui.container.ButtonGroup
        InsertionButton               matlab.ui.control.RadioButton
        MergeButton                   matlab.ui.control.RadioButton
        BubbleButton                  matlab.ui.control.RadioButton
        BucketButton                  matlab.ui.control.RadioButton
        RadixButton                   matlab.ui.control.RadioButton
        QuickButton                   matlab.ui.control.RadioButton
        HeapButton                    matlab.ui.control.RadioButton
        CountingButton                matlab.ui.control.RadioButton
        RandomizedQuickButton         matlab.ui.control.RadioButton
        CreateArrayButton             matlab.ui.control.Button
        SpeedKnobLabel                matlab.ui.control.Label
        SpeedKnob                     matlab.ui.control.DiscreteKnob
        UIAxes                        matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateArrayButton
        function CreateArrayButtonPushed(app, event)
            MinVal = app.MinimumValueSpinner.Value;
            MaxVal = app.MaximumValueSpinner.Value;
            ArrayLength = app.ArrayLengthSpinner.Value;
            
            RandomArray = randi([MinVal,MaxVal],1,ArrayLength);
            UsedArray = RandomArray;
            app.LabelSorted.Text = num2str(RandomArray);
            app.LabelUnsorted.Text = num2str(UsedArray);
        end

        % Selection changed function: SortingAlgorithmsButtonGroup
        function SortingAlgorithmsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SortingAlgorithmsButtonGroup.SelectedObject;
            import time.*
            switch(selectedButton.Text)
                case 'Insertion'
                    InsertionSortSelected;
                case 'Merge'
                    MergeSortSelected;
                case 'Bubble'
                    BubbleSortSelected;
                case 'Bucket'
                    BucketSortSelected;
                case 'Radix'
                    RadixSortSelected;
                case 'Quick'
                    QuickSortSelected;
                case 'RandomizedQuick'
                    RandomizedQuickSortSelected;
                case 'Heap'
                    HeapSortSelected;
                case 'Counting'
                    CountingSortSelected;
            end
            
            function InsertionSortSelected
                ArrayLength = int64(app.ArrayLengthSpinner.Value);
                arr = str2num(app.LabelUnsorted.Text);
                speed=2;
                bar(app.UIAxes,arr,'b')
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                pause(0.5)
                for i=1:ArrayLength % we use for because  we look at the difference between numbers
                    %speed
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    for j=1:(ArrayLength-i)
                        if arr(j+1)<arr(j)
                            value=app.SpeedKnob.Value;
                            if value=="Stop"
                                value=app.SpeedKnob.Value;
                                while true
                                    pause(0.1)
                                    if app.SpeedKnob.Value~="Stop"
                                        break
                                    end
                                end
                            end
                            if strcmp(value,'Clear')
                                cla(app.UIAxes)
                                break
                            elseif strcmp(value,'Slow')
                                speed=2;
                            elseif strcmp(value,'Normal')
                                speed=1;
                            elseif strcmp(value,'Fast')
                                speed=0.1;
                            end
                            
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j,arr(j),'g')
                            bar(app.UIAxes,j+1,arr(j+1),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')

                            temporary = arr(j);
                            arr(j) = arr(j+1);
                            arr(j+1) = temporary;
                            pause(speed)
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j,arr(j),'g')
                            bar(app.UIAxes,j+1,arr(j+1),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            pause(speed)
                            app.LabelSorted.Text = num2str(arr);
                        end
                    end
                end
            end
            
            
            function MergeSortSelected

            end
            
            function BubbleSortSelected
                q=0;
                import time.*
                arr=str2num(app.LabelUnsorted.Text);
                bar(app.UIAxes,arr,'b')
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                pause(0.5)
                speed=2;
                for i=1:length(arr)
                    %speed
                    value=(app.SpeedKnob.Value);
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear') || q>0
                        cla(app.UIAxes)
                        q=q+1;
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    
                    deneme = arr(1);
                    %deneme=en büyük değer
                    for j=2:(length(arr) - i+1)
                        %speed
                        value=(app.SpeedKnob.Value);
                        if value=="Stop"
                            value=app.SpeedKnob.Value;
                            while true
                                pause(0.1)
                                if app.SpeedKnob.Value~="Stop"
                                    break
                                end
                            end
                        end
                        if strcmp(value,'Clear') || q>0
                            cla(app.UIAxes)
                            q=q+1;
                            break
                        elseif strcmp(value,'Slow')
                            speed=2;
                        elseif strcmp(value,'Normal')
                            speed=1;
                        elseif strcmp(value,'Fast')
                            speed=0.1;
                        end
                        
                        if deneme > arr(j)
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j-1,arr(j-1),'g')
                            bar(app.UIAxes,j,arr(j),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            pause(speed)
                            
                            arr(j - 1) = arr(j);
                            arr(j) = deneme;
                            
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j-1,arr(j-1),'g')
                            bar(app.UIAxes,j,arr(j),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            pause(speed)
                            
                        elseif deneme < arr(j)
                            deneme = arr(j);
                        end
                        
                    end
                end
                if q==0
                    app.LabelSorted.Text=num2str(arr);
                end
            end
            
            function BucketSortSelected
                
            end
            
            function CountingSortSelected
                
            end
            
            function HeapSortSelected
                
            end
            
            function RadixSortSelected
                
            end
            
            function QuickSortSelected
                
            end
            
            function RandomizedQuickSortSelected
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisualizeSortTab = uitab(app.TabGroup);
            app.VisualizeSortTab.Title = 'Visualize & Sort';
            app.MinimumValueSpinnerLabel = uilabel(app.VisualizeSortTab);
            app.MinimumValueSpinnerLabel.Text = 'Minimum Value';
            app.MinimumValueSpinner = uispinner(app.VisualizeSortTab);
            app.MinimumValueSpinner.Tooltip = {'Choose lower limit of numbers'};
            app.MaximumValueSpinnerLabel = uilabel(app.VisualizeSortTab);
            app.MaximumValueSpinnerLabel.Text = 'Maximum Value';
            app.MaximumValueSpinner = uispinner(app.VisualizeSortTab);
            app.MaximumValueSpinner.Tooltip = {'Choose upper limit of numbers'};
            app.MaximumValueSpinner.Value = 100;
            app.ArrayLengthSpinnerLabel = uilabel(app.VisualizeSortTab);
            app.ArrayLengthSpinnerLabel.Text = 'Array Length';
            app.ArrayLengthSpinner = uispinner(app.VisualizeSortTab);
            app.ArrayLengthSpinner.Tooltip = {'Choose Array Length'};
            app.ArrayLengthSpinner.Value = 15;
            app.RandomArrayLabel = uilabel(app.VisualizeSortTab);
            app.RandomArrayLabel.Text = 'Random Array';
            app.SortedArrayLabel = uilabel(app.VisualizeSortTab);
            app.SortedArrayLabel.Text = 'Sorted Array';
            app.LabelUnsorted = uilabel(app.VisualizeSortTab);
            app.LabelUnsorted.Text = '';
            app.LabelSorted = uilabel(app.VisualizeSortTab);
            app.LabelSorted.Text = '';
            app.SortingAlgorithmsButtonGroup = uibuttongroup(app.VisualizeSortTab);
            app.SortingAlgorithmsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SortingAlgorithmsButtonGroupSelectionChanged, true);
            app.SortingAlgorithmsButtonGroup.Tooltip = {'Seleckt a Sorting Algorithm to see how it works.'};
            app.SortingAlgorithmsButtonGroup.Title = 'Sorting Algorithms';
            app.InsertionButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.InsertionButton.Tooltip = {'Click for Insertion Sort'};
            app.InsertionButton.Text = 'Insertion';
            app.InsertionButton.Value = true;
            app.MergeButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.MergeButton.Tooltip = {'Click for Merge Sort'};
            app.MergeButton.Text = 'Merge';
            app.BubbleButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.BubbleButton.Tooltip = {'Click for Bubble Sort'};
            app.BubbleButton.Text = 'Bubble';
            app.BucketButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.BucketButton.Tooltip = {'Click for Bucket '; 'Sort'};
            app.BucketButton.Text = 'Bucket';
            app.RadixButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.RadixButton.Tooltip = {'Click for Radix Sort'};
            app.RadixButton.Text = 'Radix';
            app.QuickButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.QuickButton.Tooltip = {'Click for Quick Sort'};
            app.QuickButton.Text = 'Quick';
            app.HeapButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.HeapButton.Tooltip = {'Click for Heap Sort'};
            app.HeapButton.Text = 'Heap';
            app.CountingButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.CountingButton.Tooltip = {'Click for Counting Sort'};
            app.CountingButton.Text = 'Counting';
            app.RandomizedQuickButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.RandomizedQuickButton.Tooltip = {'Click for Quick Sort with randomized pivot point.'};
            app.RandomizedQuickButton.Text = 'RandomizedQuick';
            app.CreateArrayButton = uibutton(app.VisualizeSortTab, 'push');
            app.CreateArrayButton.ButtonPushedFcn = createCallbackFcn(app, @CreateArrayButtonPushed, true);
            app.CreateArrayButton.Tooltip = {'Press to create random array.'};
            app.CreateArrayButton.Text = 'Create Array';
            app.SpeedKnobLabel = uilabel(app.VisualizeSortTab);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.VisualizeSortTab, 'discrete');
            app.SpeedKnob.Items = {'Stop', 'Slow', 'Normal', 'Fast', 'Clear'};
            app.SpeedKnob.Tooltip = {'Choose s'; 'peed of animation'};
            app.SpeedKnob.Value = 'Normal';
            app.UIAxes = uiaxes(app.VisualizeSortTab);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'Index')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SortingVisualizedProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end